classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Theta3EditField       matlab.ui.control.EditField
        Theta3EditFieldLabel  matlab.ui.control.Label
        Theta2EditField       matlab.ui.control.EditField
        Theta2EditFieldLabel  matlab.ui.control.Label
        Theta1EditField       matlab.ui.control.EditField
        Theta1EditFieldLabel  matlab.ui.control.Label
        ZEditField            matlab.ui.control.EditField
        ZEditFieldLabel       matlab.ui.control.Label
        YEditField            matlab.ui.control.EditField
        YEditFieldLabel       matlab.ui.control.Label
        XEditField            matlab.ui.control.EditField
        XEditFieldLabel       matlab.ui.control.Label
        ClearButton           matlab.ui.control.Button
        InverseButton         matlab.ui.control.Button
        ForwardButton         matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
L_1 = 20;
L_2 = 50;
L_3 = 40;
Theta1 = str2double(app.Theta1EditField.Value)*pi/ 180;
Theta2 = str2double(app.Theta2EditField.Value)*pi/ 180;
Theta3 = str2double(app.Theta3EditField.Value)*pi/ 180;

           L(1) = Link([0  L_1   0    pi/2]);
           L(2) = Link([0  0    L_2     0 ]);
           L(3) = Link([0  0    L_3      0]);
            Robot = SerialLink(L);
            Robot.name = 'RRR_Robot';
            Robot.plot([Theta1   Theta2  Theta3]);
            T = Robot.fkine([Theta1   Theta2  Theta3]);
            app.XEditField.Value = num2str(T.t(1));
            app.YEditField.Value = num2str(T.t(2));
            app.ZEditField.Value = num2str(T.t(3));
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            PX = str2double(app.XEditField.Value);
            PY = str2double(app.YEditField.Value);
            PZ = str2double(app.ZEditField.Value);
            L_1 = 20;
            L_2 = 50;
            L_3 = 40;
            L(1) = Link([0  L_1   0    pi/2]);
            L(2) = Link([0  0    L_2     0 ]);
            L(3) = Link([0  0    L_3      0]);
            Robot = SerialLink(L);
            Robot.name = 'RRR_Robot';
            T = [1 0 0 PX
                 0 1 0 PY
                 0 0 1 PZ
                 0 0 0 1];
            j = Robot.ikine(T, [0 0 0],'mask' ,[1 1 1 0 0 0])*180/pi;
            app.Theta1EditField.Value = string(floor(j(1)));
            app.Theta2EditField.Value = string(floor(j(2)));
            app.Theta3EditField.Value = string(floor(j(3)));
            Robot.plot(j*pi/180)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
                      s = 0
            x= num2str(s)
            app.Theta1EditField.Value = x;
            app.Theta2EditField.Value = x;
            app.Theta3EditField.Value =x;  

            app.XEditField.Value = x;
            app.YEditField.Value = x;
            app.ZEditField.Value = x;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Figure')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ForwardButton = uibutton(app.GridLayout, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.InverseButton = uibutton(app.GridLayout, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.XEditFieldLabel = uilabel(app.GridLayout);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GridLayout, 'text');
            app.YEditFieldLabel = uilabel(app.GridLayout);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GridLayout, 'text');
            app.ZEditFieldLabel = uilabel(app.GridLayout);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.GridLayout, 'text');
            app.Theta1EditFieldLabel = uilabel(app.GridLayout);
            app.Theta1EditFieldLabel.Text = 'Theta1';
            app.Theta1EditField = uieditfield(app.GridLayout, 'text');
            app.Theta2EditFieldLabel = uilabel(app.GridLayout);
            app.Theta2EditFieldLabel.Text = 'Theta2';
            app.Theta2EditField = uieditfield(app.GridLayout, 'text');
            app.Theta3EditFieldLabel = uilabel(app.GridLayout);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end